classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BrowseButton            matlab.ui.control.Button
        FilepathEditFieldLabel  matlab.ui.control.Label
        FilepathEditField       matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
        ResultsTextAreaLabel    matlab.ui.control.Label
        ResultsTextArea         matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [rawname, rawpath] = uigetfile({'*.Jpg'}, 'Select an image');
            fullname = [rawpath rawname];
            app.FilepathEditField.Value = fullname;
            
            im = imread(fullname);
            imshow(fullname, 'Parent', app.UIAxes, 'Border', 'tight');
            %axis(app.UIAxes);
            imagesc(app.UIAxes, im);
            
            hcl_hist_rvect = hcl_histo(im);
            hcl_hist_rvect = str2num(hcl_hist_rvect);

            conn = db_conn();

            selectquery = 'SELECT * FROM hcl_histo_rvector';

            data = select(conn,selectquery);
            
            
            for i = 1: 3
                y(i, :) = str2num(cell2mat(data.rvect_val(i)));
                
            end
            
            names = string({data.image_name(1), data.image_name(2), data.image_name(3)});
            euclidean_distance = [pdist2(hcl_hist_rvect, y(1, :), 'euclidean'), pdist2(hcl_hist_rvect, y(2, :), 'euclidean'), pdist2(hcl_hist_rvect, y(3, :), 'euclidean')];
            cityblock_distance = [pdist2(hcl_hist_rvect, y(1, :), 'cityblock'), pdist2(hcl_hist_rvect, y(2, :), 'cityblock'), pdist2(hcl_hist_rvect, y(3, :), 'cityblock')];
            
            euclidean_map = containers.Map(euclidean_distance, names);
            cityblock_map = containers.Map(cityblock_distance, names);
            
            sorted_euclidean_distance = sort(euclidean_distance);
            sorted_cityblock_distance = sort(cityblock_distance);
            
            results = ["1. " + extractBefore(euclidean_map(sorted_euclidean_distance(1)), "."), "    -Euclidean Distance: " + sorted_euclidean_distance(1), "    -Cityblock Distance: " + sorted_cityblock_distance(1), "2. " + extractBefore(euclidean_map(sorted_euclidean_distance(2)), "."), "    -Euclidean Distance: " + sorted_euclidean_distance(2), "    -Cityblock Distance: " + sorted_cityblock_distance(2), "3. " + extractBefore(euclidean_map(sorted_euclidean_distance(3)), "."), "    -Euclidean Distance: " + sorted_euclidean_distance(3), "    -Cityblock Distance: " + sorted_cityblock_distance(3)];


            %results = ["Euclidean distance antara " + rawname + " dengan " + data.image_name(1) + " adalah " + pdist2(hcl_hist_rvect, y(1, :), 'euclidean'),
            % "City block distance antara " + rawname + " dengan " + data.image_name(1) + " adalah " + pdist2(hcl_hist_rvect, y(1, :), 'cityblock'),
            % "Euclidean distance antara " + rawname + " dengan " + data.image_name(2) + " adalah " + pdist2(hcl_hist_rvect, y(2, :), 'euclidean'),
            % "City block distance antara " + rawname + " dengan " + data.image_name(2) + " adalah " + pdist2(hcl_hist_rvect, y(2, :), 'cityblock'),
            % "Euclidean distance antara " + rawname + " dengan " + data.image_name(3) + " adalah " + pdist2(hcl_hist_rvect, y(3, :), 'euclidean'),
           % "City block distance antara " + rawname + " dengan " + data.image_name(3) + " adalah " + pdist2(hcl_hist_rvect, y(3, :), 'cityblock')]
            
            app.ResultsTextArea.Value = results;
        
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilepathEditFieldLabel = uilabel(app.UIFigure);
            app.FilepathEditFieldLabel.Text = 'File path:';
            app.FilepathEditField = uieditfield(app.UIFigure, 'text');
            app.FilepathEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results: ';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end